classdef Suspension < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuspensionSetupUIFigure      matlab.ui.Figure
        Image2                       matlab.ui.control.Image
        BrakesPanel_2                matlab.ui.container.Panel
        InnereffectivebrakediscdiametermmEditField  matlab.ui.control.NumericEditField
        InnereffectivebrakediscdiametermmEditFieldLabel  matlab.ui.control.Label
        OutereffectivebrakediscdiametermmEditField  matlab.ui.control.NumericEditField
        OutereffectivebrakediscdiametermmEditFieldLabel  matlab.ui.control.Label
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        BrakesPanel                  matlab.ui.container.Panel
        BrakeForceNEditField         matlab.ui.control.NumericEditField
        BrakeForceNEditFieldLabel    matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        TyreParametersPanel          matlab.ui.container.Panel
        RollingResistanceEditField   matlab.ui.control.NumericEditField
        RollingResistanceEditFieldLabel  matlab.ui.control.Label
        LMUYSlider                   matlab.ui.control.Slider
        LMUYSliderLabel              matlab.ui.control.Label
        LMUXSlider                   matlab.ui.control.Slider
        LMUXSliderLabel              matlab.ui.control.Label
        p_tirePaEditField            matlab.ui.control.NumericEditField
        p_tirePaEditFieldLabel       matlab.ui.control.Label
        J_TirekgmEditField           matlab.ui.control.NumericEditField
        J_TirekgmEditFieldLabel      matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        wheelLoadRearEditField       matlab.ui.control.NumericEditField
        wheelLoadRearEditFieldLabel  matlab.ui.control.Label
        CamberEditField              matlab.ui.control.NumericEditField
        CamberEditFieldLabel         matlab.ui.control.Label
        TrackmmEditField             matlab.ui.control.NumericEditField
        TrackmmEditFieldLabel        matlab.ui.control.Label
        WheelbasemmEditField         matlab.ui.control.NumericEditField
        WheelbasemmEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function saveData(app)
            app.CallingApp.wheelbase = app.WheelbasemmEditField.Value;
            app.CallingApp.track = app.TrackmmEditField.Value;
            app.CallingApp.J_Tire = app.J_TirekgmEditField.Value;
            app.CallingApp.p_Tire = app.p_tirePaEditField.Value;
            app.CallingApp.LMUX = app.LMUXSlider.Value;
            app.CallingApp.LMUY = app.LMUYSlider.Value;
            app.CallingApp.k_R = app.RollingResistanceEditField.Value;
            app.CallingApp.FB = app.BrakeForceNEditField.Value;
            app.CallingApp.camber = app.CamberEditField.Value;
            app.CallingApp.m_ph = app.wheelLoadRearEditField.Value;
        end
        
        function loadApp(app)
            % Loads data from main app
            app.WheelbasemmEditField.Value = app.CallingApp.wheelbase;
            app.TrackmmEditField.Value = app.CallingApp.track;
            app.J_TirekgmEditField.Value = app.CallingApp.J_Tire;
            app.p_tirePaEditField.Value = app.CallingApp.p_Tire;
            app.LMUXSlider.Value = app.CallingApp.LMUX;
            app.LMUYSlider.Value = app.CallingApp.LMUY;
            app.RollingResistanceEditField.Value = app.CallingApp.k_R;
            app.BrakeForceNEditField.Value = app.CallingApp.FB;
            app.CamberEditField.Value = app.CallingApp.camber;
            app.wheelLoadRearEditField.Value = app.CallingApp.m_ph;
        end
    end
    
    methods (Access = public)
        
        function changeColorScheme(app)       
                PrimaryColor = app.CallingApp.PrimaryColor;
                OnPrimaryColor = app.CallingApp.OnPrimaryColor;   
                PrimaryVariantColor = app.CallingApp.PrimaryVariantColor;
                OnPrimaryVariantColor = app.CallingApp.OnPrimaryVariantColor;
                SecondaryColor = app.CallingApp.SecondaryColor;
                OnSecondaryColor = app.CallingApp.OnSecondaryColor;
                BackgroundColor = app.CallingApp.BackgroundColor;
                OnBackgroundColor = app.CallingApp.OnBackgroundColor;
                SurfaceColor = app.CallingApp.SurfaceColor;
                OnSurfaceColor = app.CallingApp.OnSurfaceColor;
                ErrorColor = app.CallingApp.ErrorColor;
                OnErrorColor = app.CallingApp.OnErrorColor;
                BorderType = app.CallingApp.BorderType;                 
                Host_Logo = app.CallingApp.Host_Logo;
                
                app.SuspensionSetupUIFigure.Color = BackgroundColor;
                app.Panel.BackgroundColor = SurfaceColor;
                app.Panel.ForegroundColor = OnSurfaceColor;
                app.Panel.BorderType = BorderType;
                
                app.Copyright2021BalticRacingbyEricDorniedenLabel.FontColor = OnBackgroundColor;
                
                app.WheelbasemmEditFieldLabel.FontColor = OnPrimaryColor;
                app.WheelbasemmEditField.BackgroundColor = SurfaceColor;
                app.WheelbasemmEditField.FontColor = OnSurfaceColor;
                
                app.TrackmmEditFieldLabel.FontColor = OnPrimaryColor;
                app.TrackmmEditField.BackgroundColor = SurfaceColor;
                app.TrackmmEditField.FontColor = OnSurfaceColor;
                
                app.CamberEditFieldLabel.FontColor = OnPrimaryColor;
                app.CamberEditField.BackgroundColor = SurfaceColor;
                app.CamberEditField.FontColor = OnSurfaceColor;
                
                app.wheelLoadRearEditFieldLabel.FontColor = OnPrimaryColor;
                app.wheelLoadRearEditField.BackgroundColor = SurfaceColor;
                app.wheelLoadRearEditField.FontColor = OnSurfaceColor;
                
                app.TyreParametersPanel.BackgroundColor = SurfaceColor;
                app.TyreParametersPanel.ForegroundColor = OnSurfaceColor;
                app.TyreParametersPanel.BorderType = BorderType;
                
                app.J_TirekgmEditFieldLabel.FontColor = OnPrimaryColor;
                app.J_TirekgmEditField.BackgroundColor = SurfaceColor;
                app.J_TirekgmEditField.FontColor = OnSurfaceColor;
                
                app.p_tirePaEditFieldLabel.FontColor = OnPrimaryColor;
                app.p_tirePaEditField.BackgroundColor = SurfaceColor;
                app.p_tirePaEditField.FontColor = OnSurfaceColor;
                
                app.LMUXSliderLabel.FontColor = OnPrimaryColor;
                app.LMUXSlider.FontColor = OnPrimaryColor;
                
                app.LMUYSliderLabel.FontColor = OnPrimaryColor;
                app.LMUYSlider.FontColor = OnPrimaryColor;
                
                app.RollingResistanceEditFieldLabel.FontColor = OnPrimaryColor;
                app.RollingResistanceEditField.BackgroundColor = SurfaceColor;
                app.RollingResistanceEditField.FontColor = OnSurfaceColor;
                
                app.BrakeForceNEditFieldLabel.FontColor = OnPrimaryColor;
                app.BrakeForceNEditField.BackgroundColor = SurfaceColor;
                app.BrakeForceNEditField.FontColor = OnSurfaceColor;
                
                app.BrakesPanel.BackgroundColor = SurfaceColor;
                app.BrakesPanel.ForegroundColor = OnSurfaceColor;
                app.BrakesPanel.BorderType = BorderType;
                
                app.BrakesPanel_2.BackgroundColor = SurfaceColor;
                app.BrakesPanel_2.ForegroundColor = OnSurfaceColor;
                app.BrakesPanel_2.BorderType = BorderType;
                
                app.OutereffectivebrakediscdiametermmEditFieldLabel.FontColor = OnPrimaryColor;
                app.OutereffectivebrakediscdiametermmEditField.BackgroundColor = SurfaceColor;
                app.OutereffectivebrakediscdiametermmEditField.FontColor = OnSurfaceColor;
                
                app.InnereffectivebrakediscdiametermmEditFieldLabel.FontColor = OnPrimaryColor;
                app.InnereffectivebrakediscdiametermmEditField.BackgroundColor = SurfaceColor;
                app.InnereffectivebrakediscdiametermmEditField.FontColor = OnSurfaceColor;
                
                app.ResetButton.FontColor = OnPrimaryColor;
                app.ResetButton.BackgroundColor = PrimaryVariantColor;
                
                app.SaveButton.FontColor = OnPrimaryColor;
                app.SaveButton.BackgroundColor = PrimaryVariantColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
            
            loadApp(app);
            
            changeColorScheme(app);
        end

        % Close request function: SuspensionSetupUIFigure
        function SuspensionSetupUIFigureCloseRequest(app, event)
            saveData(app);
            
            % Disable Suspension Options button while dialog is open
            app.CallingApp.SuspensionButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            loadApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuspensionSetupUIFigure = uifigure('Visible', 'off');
            app.SuspensionSetupUIFigure.Name = 'Suspension Setup';
            app.SuspensionSetupUIFigure.Icon = 'Baltic.png';
            app.SuspensionSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @SuspensionSetupUIFigureCloseRequest, true);
            app.Panel = uipanel(app.SuspensionSetupUIFigure);
            app.Panel.Title = 'Panel';
            app.WheelbasemmEditFieldLabel = uilabel(app.Panel);
            app.WheelbasemmEditFieldLabel.Text = 'Wheelbase [mm]';
            app.WheelbasemmEditField = uieditfield(app.Panel, 'numeric');
            app.TrackmmEditFieldLabel = uilabel(app.Panel);
            app.TrackmmEditFieldLabel.Text = 'Track [mm]';
            app.TrackmmEditField = uieditfield(app.Panel, 'numeric');
            app.CamberEditFieldLabel = uilabel(app.Panel);
            app.CamberEditFieldLabel.Text = 'Camber [°]';
            app.CamberEditField = uieditfield(app.Panel, 'numeric');
            app.wheelLoadRearEditFieldLabel = uilabel(app.Panel);
            app.wheelLoadRearEditFieldLabel.Text = 'wheel Load Rear [%]';
            app.wheelLoadRearEditField = uieditfield(app.Panel, 'numeric');
            app.TyreParametersPanel = uipanel(app.SuspensionSetupUIFigure);
            app.TyreParametersPanel.Title = 'Tyre Parameters';
            app.J_TirekgmEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.J_TirekgmEditFieldLabel.Text = 'J_Tire [kg*m²]';
            app.J_TirekgmEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.p_tirePaEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.p_tirePaEditFieldLabel.Text = 'p_tire [Pa]';
            app.p_tirePaEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.p_tirePaEditField.ValueDisplayFormat = '%.0f';
            app.LMUXSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUXSliderLabel.Text = 'LMUX';
            app.LMUXSlider = uislider(app.TyreParametersPanel);
            app.LMUXSlider.Limits = [0.6 0.75];
            app.LMUXSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUXSlider.Value = 0.65;
            app.LMUYSliderLabel = uilabel(app.TyreParametersPanel);
            app.LMUYSliderLabel.Text = 'LMUY';
            app.LMUYSlider = uislider(app.TyreParametersPanel);
            app.LMUYSlider.Limits = [0.6 0.75];
            app.LMUYSlider.MajorTicks = [0.6 0.65 0.7 0.75];
            app.LMUYSlider.Value = 0.65;
            app.RollingResistanceEditFieldLabel = uilabel(app.TyreParametersPanel);
            app.RollingResistanceEditFieldLabel.Text = 'Rolling Resistance [-]';
            app.RollingResistanceEditField = uieditfield(app.TyreParametersPanel, 'numeric');
            app.RollingResistanceEditField.ValueDisplayFormat = '%.3f';
            app.SaveButton = uibutton(app.SuspensionSetupUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.SuspensionSetupUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BrakesPanel = uipanel(app.SuspensionSetupUIFigure);
            app.BrakesPanel.Title = 'Brakes';
            app.BrakeForceNEditFieldLabel = uilabel(app.BrakesPanel);
            app.BrakeForceNEditFieldLabel.Text = 'Brake Force [N]';
            app.BrakeForceNEditField = uieditfield(app.BrakesPanel, 'numeric');
            app.BrakeForceNEditField.ValueDisplayFormat = '%.0f';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.SuspensionSetupUIFigure);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.BrakesPanel_2 = uipanel(app.SuspensionSetupUIFigure);
            app.BrakesPanel_2.Title = 'Brakes';
            app.OutereffectivebrakediscdiametermmEditFieldLabel = uilabel(app.BrakesPanel_2);
            app.OutereffectivebrakediscdiametermmEditFieldLabel.Text = 'Outer effective brake disc diameter [mm]';
            app.OutereffectivebrakediscdiametermmEditField = uieditfield(app.BrakesPanel_2, 'numeric');
            app.OutereffectivebrakediscdiametermmEditField.ValueDisplayFormat = '%.0f';
            app.InnereffectivebrakediscdiametermmEditFieldLabel = uilabel(app.BrakesPanel_2);
            app.InnereffectivebrakediscdiametermmEditFieldLabel.Text = 'Inner effective brake disc diameter [mm]';
            app.InnereffectivebrakediscdiametermmEditField = uieditfield(app.BrakesPanel_2, 'numeric');
            app.InnereffectivebrakediscdiametermmEditField.ValueDisplayFormat = '%.0f';
            app.Image2 = uiimage(app.SuspensionSetupUIFigure);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            % Show the figure after all components are created
            app.SuspensionSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Suspension(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SuspensionSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuspensionSetupUIFigure)
        end
    end
end