classdef laptime_Simulation_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaptimeSimulationUIFigure      matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HowtoTab                       matlab.ui.container.Tab
        StyleSettingsButton            matlab.ui.control.Button
        DarkModeCheckBox               matlab.ui.control.CheckBox
        LaptimeSimulationGitHubButton  matlab.ui.control.Button
        WikiBalticRacingButton         matlab.ui.control.Button
        PatchNotesPanel                matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        ContactmeButton                matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Copyright2021BalticRacingbyEricDorniedenLabel  matlab.ui.control.Label
        DailyUpdateFeatureRequestsButton  matlab.ui.control.Button
        HowToLaptimeSimulationPanel    matlab.ui.container.Panel
        EditthecarsetupintheCarSetuptab2SaveCarSetupLabel  matlab.ui.control.Label
        CarSetupTab                    matlab.ui.container.Tab
        Image_2                        matlab.ui.control.Image
        EditSetupPanel                 matlab.ui.container.Panel
        VariableListButton             matlab.ui.control.Button
        SetupPresetsPanel              matlab.ui.container.Panel
        TY22Panel                      matlab.ui.container.Panel
        NoDownforceButton_3            matlab.ui.control.Button
        HighDownforceButton_3          matlab.ui.control.Button
        MedDownforceButton_3           matlab.ui.control.Button
        LowDownforceButton_3           matlab.ui.control.Button
        TY20Panel                      matlab.ui.container.Panel
        NoDownforceButton_2            matlab.ui.control.Button
        HighDownforceButton_2          matlab.ui.control.Button
        MedDownforceButton_2           matlab.ui.control.Button
        LowDownforceButton_2           matlab.ui.control.Button
        TY19Panel                      matlab.ui.container.Panel
        NoDownforceButton              matlab.ui.control.Button
        HighDownforceButton            matlab.ui.control.Button
        MedDownforceButton             matlab.ui.control.Button
        LowDownforceButton             matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        EnviromentPanel                matlab.ui.container.Panel
        EnvironmentButton              matlab.ui.control.Button
        CarParametersPanel             matlab.ui.container.Panel
        AccumulatorButton              matlab.ui.control.Button
        AerodynamikButton              matlab.ui.control.Button
        DrivetrainButton               matlab.ui.control.Button
        ChassisButton                  matlab.ui.control.Button
        SuspensionButton               matlab.ui.control.Button
        Image2_2                       matlab.ui.control.Image
        Copyright2021BalticRacingbyEricDorniedenLabel_2  matlab.ui.control.Label
        SimulationSetupTab             matlab.ui.container.Tab
        TrackDataOptimizationSpinner   matlab.ui.control.Spinner
        TrackDataOptimizationSpinnerLabel  matlab.ui.control.Label
        RunUnitTestsButton             matlab.ui.control.Button
        UseoldbrakesystemCheckBox      matlab.ui.control.CheckBox
        LogCellDataCheckBox            matlab.ui.control.CheckBox
        EnableDebugMessagestimeintensivCheckBox  matlab.ui.control.CheckBox
        Copyright2021BalticRacingbyEricDorniedenLabel_3  matlab.ui.control.Label
        SimulationStatusPanel          matlab.ui.container.Panel
        ClearButton                    matlab.ui.control.Button
        SimulationTextArea             matlab.ui.control.TextArea
        LengthLabel                    matlab.ui.control.Label
        ScenarioPanel                  matlab.ui.container.Panel
        TrackManagerButton             matlab.ui.control.Button
        UpdateTracksButton             matlab.ui.control.Button
        DisciplineDropDown             matlab.ui.control.DropDown
        DisciplineDropDownLabel        matlab.ui.control.Label
        TrackDropDown                  matlab.ui.control.DropDown
        TrackDropDownLabel             matlab.ui.control.Label
        SensitivityAnalysisPanel       matlab.ui.container.Panel
        StepsizeEditField_2            matlab.ui.control.NumericEditField
        MinEditField_2                 matlab.ui.control.NumericEditField
        secondVariableCheckBox         matlab.ui.control.CheckBox
        VariableDropDown_2             matlab.ui.control.DropDown
        NumStepsEditField              matlab.ui.control.NumericEditField
        NumStepsEditFieldLabel         matlab.ui.control.Label
        sensitivityCheckBox            matlab.ui.control.CheckBox
        StepsizeEditField              matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel         matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        VariableDropDown               matlab.ui.control.DropDown
        VariableDropDownLabel          matlab.ui.control.Label
        StartSimulationButton          matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        SimulationResultsTab           matlab.ui.container.Tab
        TelemetryconverterButton       matlab.ui.control.Button
        OpenTrackAnalyzerButton        matlab.ui.control.Button
        Copyright2021BalticRacingbyEricDorniedenLabel_4  matlab.ui.control.Label
        CompareRunsPanel               matlab.ui.container.Panel
        SelectPlotDropDown_2           matlab.ui.control.DropDown
        SelectPlotDropDown_3Label      matlab.ui.control.Label
        CompareRunPlotsButton          matlab.ui.control.Button
        PlotResultsPanel               matlab.ui.container.Panel
        RunnumberEditField             matlab.ui.control.NumericEditField
        RunnumberEditFieldLabel        matlab.ui.control.Label
        DrawPlot2RunButton             matlab.ui.control.Button
        DrawPlot1RunButton             matlab.ui.control.Button
        SelectPlotDropDown             matlab.ui.control.DropDown
        SelectPlotDropDownLabel        matlab.ui.control.Label
        SensitivityAnalysisPanel_2     matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        ResolutionyEditField           matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        ResolutionxEditField           matlab.ui.control.NumericEditField
        ResolutionxEditFieldLabel      matlab.ui.control.Label
        zAxisCheckBox                  matlab.ui.control.CheckBox
        zaxisDropDown_2                matlab.ui.control.DropDown
        zaxisDropDown_2Label           matlab.ui.control.Label
        DrawDotsCheckBox               matlab.ui.control.CheckBox
        DrawSensitivityPlotButton      matlab.ui.control.Button
        yaxisDropDown_2                matlab.ui.control.DropDown
        yaxisDropDown_2Label           matlab.ui.control.Label
        xaxisDropDown_2                matlab.ui.control.DropDown
        xaxisDropDown_2Label           matlab.ui.control.Label
        DeltaSecondRunFirstRunPanel    matlab.ui.container.Panel
        WrecuperationLabel_3           matlab.ui.control.Label
        EnergyconsumptionWOrecuperationLabel_3  matlab.ui.control.Label
        LapDistanceLabel_3             matlab.ui.control.Label
        EndurancetimeLabel_3           matlab.ui.control.Label
        t_lapLabel_3                   matlab.ui.control.Label
        Results2RunPanel               matlab.ui.container.Panel
        SkidPadSpeedLabel_2            matlab.ui.control.Label
        SkidPadTimeLabel_2             matlab.ui.control.Label
        EnergyconsumptionLabel_2       matlab.ui.control.Label
        withrecuperationLabel_2        matlab.ui.control.Label
        ComputationtimeLabel_2         matlab.ui.control.Label
        EndurancetimeLabel_2           matlab.ui.control.Label
        EnduranceDistanceLabel_2       matlab.ui.control.Label
        PowerLimitLabel_2              matlab.ui.control.Label
        LapDistanceLabel_2             matlab.ui.control.Label
        t_lapLabel_2                   matlab.ui.control.Label
        DataInspectorPanel             matlab.ui.container.Panel
        ExportasdistanceCheckBox       matlab.ui.control.CheckBox
        DeletecsvfileCheckBox          matlab.ui.control.CheckBox
        ResultfilenameEditField        matlab.ui.control.EditField
        ResultfilenameEditFieldLabel   matlab.ui.control.Label
        LoadSetup2RunButton            matlab.ui.control.Button
        LoadSetup1RunButton            matlab.ui.control.Button
        SetupviewerTextArea            matlab.ui.control.TextArea
        SetupviewerTextAreaLabel       matlab.ui.control.Label
        CreateData2RunButton           matlab.ui.control.Button
        CreateData1RunButton           matlab.ui.control.Button
        SelectLapRunnumberEditField    matlab.ui.control.NumericEditField
        SelectLapRunnumberEditFieldLabel  matlab.ui.control.Label
        OpenDataInspectorButton        matlab.ui.control.Button
        Load2RunButton                 matlab.ui.control.Button
        Results1RunPanel               matlab.ui.container.Panel
        SkidPadSpeedLabel              matlab.ui.control.Label
        SkidPadTimeLabel               matlab.ui.control.Label
        EnduranceDistanceLabel         matlab.ui.control.Label
        PowerLimitLabel                matlab.ui.control.Label
        ComputationtimeLabel           matlab.ui.control.Label
        withrecuperationLabel          matlab.ui.control.Label
        EnergyconsumptionLabel         matlab.ui.control.Label
        LapDistanceLabel               matlab.ui.control.Label
        EndurancetimeLabel             matlab.ui.control.Label
        t_lapLabel                     matlab.ui.control.Label
        Load1RunButton                 matlab.ui.control.Button
        SimulationReplayWIPTab         matlab.ui.container.Tab
        Slider_2                       matlab.ui.control.Slider
        BrakeLamp                      matlab.ui.control.Lamp
        BrakeLampLabel                 matlab.ui.control.Label
        DRSStatusLamp                  matlab.ui.control.Lamp
        DRSStatusLampLabel             matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        StopButton                     matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        TimeLabel                      matlab.ui.control.Label
        RadiusLabel                    matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        DistanceLabel                  matlab.ui.control.Label
        RPMGauge                       matlab.ui.control.Gauge
        SpeedLabel                     matlab.ui.control.Label
        Gauge_4                        matlab.ui.control.LinearGauge
        RPMLabel                       matlab.ui.control.Label
        GearLabel                      matlab.ui.control.Label
        Gauge_3                        matlab.ui.control.LinearGauge
        Gauge_2                        matlab.ui.control.LinearGauge
        Gauge                          matlab.ui.control.LinearGauge
        Label_6                        matlab.ui.control.Label
        DropDown_6                     matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        DropDown_5                     matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        DropDown_4                     matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        DropDown_3                     matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Image_3                        matlab.ui.control.Image
        Image2_3                       matlab.ui.control.Image
        Copyright2021BalticRacingbyEricDorniedenLabel_5  matlab.ui.control.Label
        RunNumberSpinner               matlab.ui.control.Spinner
        RunNumberSpinnerLabel          matlab.ui.control.Label
        LoadReplayButton               matlab.ui.control.Button
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        ColorSchemeID = 0;
        ErrorLog = [];
        settings;
        
        Tracks;
        TrackFileName;
        StartingSpeed;
        numOfLaps;
        
        % Color Scheme Variables
        ColorsDark = ["#BB86FC"; "white"; "#3700B3"; "#000000"; "#03DAC6"; "#000000"; "#121212"; "#FFFFFF"; "#2a2a2a"; "#FFFFFF"; "#CF6679"; "#000000"; "none"; "Logo_Ship_HKS_Ranged_Dark.png"]; % Used for Customization GUI
        ColorsBright = ["[0.96 0.96 0.96]"; "#000000"; "[0.96 0.96 0.96]"; "#000000"; "#03DAC6"; "#000000"; "[0.94 0.94 0.94]"; "black"; "white"; "black"; "#CF6679"; "#000000"; "line"; "Logo_Ship_HKS_Ranged.png"]; % Used for Customization GUI
        PrimaryColor;
        OnPrimaryColor;           
        PrimaryVariantColor;
        OnPrimaryVariantColor;
        SecondaryColor;
        OnSecondaryColor;
        BackgroundColor;
        OnBackgroundColor;
        SurfaceColor;
        OnSurfaceColor;
        ErrorColor;
        OnErrorColor;
        BorderType;                   
        Host_Logo;
        
        %% Chassis Parameters
        m_ges = 280; % Description
        h_cog = 250; % Description
        x_cog = 1348;
        x_va = 470;
        m_driver = 0;
        h_cog_driver = 0;
        x_cog_driver = 0;
        m_ballast = 0;
        h_cog_ballast = 0;
        x_cog_ballast = 0;
        thetaV_X = 29.3828;  % [kg*m²] Trägheitsmoment Fahrzeug um X-Achse x
        thetaV_Y = 104.837;  % [kg*m²] Trägheitsmoment Fahrzeug um Y-Achse x
        thetaV_Z = 119.2005; % [kg*m²] Trägheitsmoment Fahrzeug um Z-Achse x
        
        %% Suspension Parameters
        wheelbase = 1530;
        track = 1200;
        J_Tire = 0.2;
        p_Tire = 65000;
        LMUX = 0.65;
        LMUY = 0.65;
        LXAL = 1.2;
        LYKA = 1.2;
        RBX3 = 0;
        k_R = 0.01;
        FB = 7000;
        camber = 0;
        m_ph = 50;
        R0 = 0.235;
        
        %% Drivetrain Parameters
        ptype = 1; % powertrain type 1 = Electric; 0 = Combustion
        
        p_max = 80000;
        n_max = 6000;
        drivetrain_eff = 0.95;
        invertor_eff = 0.98;
        z_chaindrive = 66;        
        z_sprocket = 17;
        trq_multiplier = 1;
        engine_param = [0 140; 1000 140; 2000 138; 3000 136; 4000 135; 5000 127; 6000 115];
        num_motors = 2;
        i_P = 1;
        brakeBias = -1;
        
        % Gearbox
        gearbox = 0;
        i_param = [1.857; 1.565; 1.35; 1.238; 1.136];
        n_shift = 10500;
        n_downshift = 6000;
        t_shift = 0.035;
        idleRPM = 2000;
        
        %% Aerodynamic Parameters
        c_w = 1.463;
        c_l = 3;
        A = 0.5428548*2;
        downforce_data = [0 0; 30 162; 50 456; 60 642];
        downforce_multiplier = 1;
        aero_pv = 0.5;
        DRS = false;
        c_d_DRS = 1.2;
        c_l_DRS = 2.5;
        ConstantDownforce = 0;
        DRS_Radius = 300;
        
        %% Accumulator Parameters       
        V_i = 550;
        Energy_i = 7.5;
        nZellen_Parallel = 5; 
        nZellen_Reihe = 131;
        capacity_cell = 10.8;
        
        %% Environment Parameters
        t_L = 30;
        p_L = 101300;
        R_L = 287;
        g = 9.81;      
        
        %% Result Parameters Run 1
        file_run_1;
        result;          % Contains all values from the selected result file
        x_Track;         % [m] X-Koordinate der Strecke
        y_Track;         % [m] Y-Koordinate der Strecke
        z_Track;         % [m] Z-Koordinate der Strecke
        s;               % [m] Verlauf der Streckenlänge
        R; 
        t_ges;
        ni;
        
        %% Result Parameters Run 2
        file_run_2;
        result_2;          % Contains all values from the selected result file
        x_Track_2;         % [m] X-Koordinate der Strecke
        y_Track_2;         % [m] Y-Koordinate der Strecke
        z_Track_2;         % [m] Z-Koordinate der Strecke
        s_2;               % [m] Verlauf der Streckenlänge
        R_2; 
        t_ges_2;
        ni_2;      
        
        %% Result Parameters Run 3 (Simulation Replay)
        file_run_3;
        result_3;          % Contains all values from the selected result file
        x_Track_3;         % [m] X-Koordinate der Strecke
        y_Track_3;         % [m] Y-Koordinate der Strecke
        z_Track_3;         % [m] Z-Koordinate der Strecke
        s_3;               % [m] Verlauf der Streckenlänge
        R_3; 
        t_ges_3;
        ni_3; 
        
        ChassisApp      % Chassis options app
        SuspensionApp   % Suspension options app
        DrivetrainApp   % Drivetrain options app
        EnvironmentApp  % Environment options app
        AerodynamikApp  % Aerodynamic options app
        AccumulatorApp   % Acumulator options app
        TrackAnalyzerApp % TrackAnalyzer App (for debugging etc.)
        TelemetryConverterApp   % TelemetryConverterApp for converting logged data to format usable in data inspector
        TrackManagerApp    % TrackManager app
        CustomizationApp   % Customization app (Color Schemes and Fonts)
    end    
       
    methods (Access = private) 
        
        function compareRuns(app)
            %% Set Result Labels
            
            % Lap time Delta
            delta_t_lap = app.result_2(1).t(end)-app.result(1).t(end);
            
            % Sets FontColor to red if lap is slower
            if(delta_t_lap > 0)
                app.t_lapLabel_3.FontColor = 'red';
            else
                app.t_lapLabel_3.FontColor = 'green';
            end
            % Sets text label
            app.t_lapLabel_3.Text = "Lap time = " + delta_t_lap + " s";
                        
            % Endurance time Label
            delta_t_overall = app.result_2(1).t(end)-app.result(1).t(end);
            % Sets FontColor to red if time is slower
            if(delta_t_overall > 0)
                app.EndurancetimeLabel_3.FontColor = 'red';
            else
                app.EndurancetimeLabel_3.FontColor = 'green';
            end
            app.EndurancetimeLabel_3.Text = "Endurance time = " + delta_t_overall + " s";
            
            % Lap Distance Label
            delta_distance = app.s_2(end)-app.s(end);
            % Sets FontColor to red if lap distance is not equal zero
            if(delta_distance ~= 0)
                app.LapDistanceLabel_3.FontColor = 'red';
            else
                app.LapDistanceLabel_3.FontColor = 'green';
            end
            app.LapDistanceLabel_3.Text = "Lap Distance = " + delta_distance + " m";
            
            % E Accumulator w/o recu
            delta_E_Accu = (app.result_2(1).E_Accu(end) * (22000/app.s_2(end)))-(app.result(1).E_Accu(end) * (22000/app.s(end)));
            % Sets FontColor to red if Energy consumption is higher
            if(delta_E_Accu > 0)
                app.EnergyconsumptionWOrecuperationLabel_3.FontColor = 'red';
            else
                app.EnergyconsumptionWOrecuperationLabel_3.FontColor = 'green';
            end
            app.EnergyconsumptionWOrecuperationLabel_3.Text = "Energy consumption W/O recuperation = " + delta_E_Accu + " kWh";
            
            % E Accumulator w recu
            delta_E_Accu_ges = (app.result_2(1).E_res(end) * (22000/app.s_2(end)))-(app.result(1).E_res(end) * (22000/app.s(end)));
            % Sets FontColor to red if Energy consumption is higher
            if(delta_E_Accu_ges > 0)
                app.WrecuperationLabel_3.FontColor = 'red';
            else
                app.WrecuperationLabel_3.FontColor = 'green';
            end
            app.WrecuperationLabel_3.Text = "W/O recuperator = " + delta_E_Accu_ges + " kWh";
        end
        
        function renderSelectedTrack(app)

            
            app.TrackFileName = app.Tracks.Data(app.TrackDropDown.Value,7); 
            
            load(app.TrackFileName,'Track');     % Streckendaten Endurance Track
            x_Track_preview = Track(:,1);           % [m] X-Koordinate der Strecke
            y_Track_preview = Track(:,2);           % [m] Y-Koordinate der Strecke
            s_preview = Track(:,4);                 % [m] Verlauf der Streckenlänge
            TrackName = app.Tracks.Data(app.TrackDropDown.Value,1);
            app.StartingSpeed = str2double(app.Tracks.Data(app.TrackDropDown.Value,6));
            app.numOfLaps = str2double(app.Tracks.Data(app.TrackDropDown.Value,5));
            
            % Enables the selection dropdown for the discipline
            if str2double(app.Tracks.Data(app.TrackDropDown.Value,4))
                app.DisciplineDropDown.Enable = true;
            else
                app.DisciplineDropDown.Enable = false;
            end
               
            app.UIAxes2.reset; 
            
            if app.DarkModeCheckBox.Value
                plot(app.UIAxes2,x_Track_preview,y_Track_preview,'w')
            else
                plot(app.UIAxes2,x_Track_preview,y_Track_preview,'k')   
            end
            
            title(app.UIAxes2,TrackName,'FontSize',12);
            app.UIAxes2.LineWidth = 1.5;
            
            % Sets Color to Dark or white Mode
            app.UIAxes2.Title.Color = app.OnPrimaryColor;
            app.UIAxes2.XColor = app.OnPrimaryColor;
            app.UIAxes2.YColor = app.OnPrimaryColor;
            app.UIAxes2.Color = app.SurfaceColor;       
            
            app.LengthLabel.Text = "Track Length: " + max(s_preview) + " m";
        end            
        
        % Resets the Simulation text Area to the standard text
        function clearSimulationStatus(app)
            app.SimulationTextArea.Value = 'The status of the current simulation is displayed here. (Only works when debug messages are enabled)';
        end    
        
        function displaySetupData(app, result)
            
            % gets the runID which should be used (number of lap)
            runID = app.SelectLapRunnumberEditField.Value;
            
           if checkRunID(app, runID, result)
                return;
           end
            
            % Clears Setup viewer
            app.SetupviewerTextArea.Value = '';            
            
            % Sets the data of the setup viewer
            app.SetupviewerTextArea.Value = {['A ' num2str(result(runID).A(:)) ' m²']; ['camber ' num2str(result(runID).camber(:)) ' °']; ['LMUX ' num2str(result(runID).LMUX(:))];...
                ['LMUY ' num2str(result(runID).LMUY(:))]; ['p_max ' num2str(result(runID).p_max(:)) ' W']; ['aero_ph ' num2str(result(runID).aero_ph(:))];...
                ['aero_pv ' num2str(result(runID).aero_pv(:))]; ['c_l ' num2str(result(runID).c_l(:))]; ['c_d ' num2str(result(runID).c_w(:))];...
                ['downforce_multiplier ' num2str(result(runID).downforce_multiplier(:))]; ['Constant Downforce ' num2str(result(runID).ConstantDownforce(:)) ' N']; ['drivetrain_eff ' num2str(result(runID).drivetrain_eff(:))];...
                ['h_cog ' num2str(result(runID).h_cog(:)) ' mm']; ['h_COG_ballast ' num2str(result(runID).h_cog_ballast(:)) ' mm']; ['h_COG_driver ' num2str(result(runID).h_cog_driver(:)) ' mm'];...
                ['i_G ' num2str(result(runID).i_G(:))];...
                ['m_balast ' num2str(result(runID).m_ballast(:)) ' kg']; ['m_driver ' num2str(result(runID).m_driver(:)) ' kg'];...
                ['m_ph ' num2str(result(runID).m_ph(:)) ' %']; ['m_tot ' num2str(result(runID).m_ges(:)) ' kg']; ['n_Mmax ' num2str(result(runID).n_max(:)) ' 1/min'];...
                ['n_downshift ' num2str(result(runID).n_downshift(:)) ' 1/min']; ['n_shift ' num2str(result(runID).n_shift(:)) ' 1/min']; ['num_motors ' num2str(result(runID).num_motors(:))];...
                ['p_infl ' num2str(result(runID).p_Tire(:)) ' Pa']; ['rho_L ' num2str(result(runID).rho_L(:)) ' kg/m³']; ['t_L ' num2str(result(runID).t_L(:)) ' °C']; ['thetaV_X ' num2str(result(runID).thetaV_X(:))];...
                ['thetaV_Y ' num2str(result(runID).thetaV_Y(:))]; ['thetaV_Z ' num2str(result(runID).thetaV_Z(:))]; ['track ' num2str(result(runID).track(:)) ' m']; ['trq_multiplier ' num2str(result(runID).trq_multiplier(:))];...
                ['wheelbase ' num2str(result(runID).wheelbase(:)) ' mm']; ['x_cog ' num2str(result(runID).x_cog(:)) ' mm']; ['x_vA ' num2str(result(runID).x_va(:)) ' mm'];...
                ['z_chaindrive ' num2str(result(runID).z_chaindrive(:))]; ['z_pinion ' num2str(result(runID).z_sprocket(:))]};
            
                % Add the gears of the gearbox to the setup viewer
                for i = 1:size(result(runID).i_param)
                    app.SetupviewerTextArea.Value{end+1} = ['gear ratio ' num2str(i) '. gear ' num2str(result(runID).i_param(i))];
                end
        end
        
        function updateTracks(app)
            
            app.Tracks = load('Tracks.mat', '-mat');
            
            app.TrackDropDown.Items = app.Tracks.Data(:,1); 
            app.TrackDropDown.ItemsData = 1:size(app.Tracks.Data(:,1));
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             close all;
             clc;

            addpath('.\Simulation')
            addpath('.\Presets')
            addpath('.\Setups and Results')
            addpath('.\laptime_Simulation_GUI_resources')
            addpath('.\LaptimeSimulation_resources')
            
            % Try to load a settings file and create it, if it doesn't
            % exist
            try
                % Load the settings .mat file
                app.settings = load('Settings.mat', '-mat');
                
                % Set Dark Mode checkbox to checked if Dark Mode is
                % activated
                if app.settings.ColorScheme
                    app.DarkModeCheckBox.Value = 1;
                    app.ColorSchemeID = 1;
                end
                
                app.ColorsDark = app.settings.ColorsDark;
                app.ColorsBright = app.settings.ColorsBright;
                
            catch         
                app.settings.ColorScheme = 0;
                app.settings.ColorsDark = app.ColorsDark;
                app.settings.ColorsBright = app.ColorsBright;
                
                settings = app.settings; %#ok<ADPROP> 
                save('Settings.mat','-struct','settings')
            end
            
            % parameters for sensitivity analysis
            sensitvityData = {'A','FB','LMUX','LMUY','aero_pv','c_l','c_w','camber','downforce_multiplier','drivetrain_eff','m_balast','m_driver','m_ges','m_ph','n_max','num_motors','p_Tire','p_max','t_L','thetaV_X','thetaV_Y','thetaV_Z','track','trq_multiplier','wheelbase','h_cog','x_cog','x_cog_balast','x_cog_driver','x_va','z_chaindrive','z_sprocket','i_g','ConstantDownforce','DRS_Radius','vV_skidpad','t_skidpad'};
            DropDownData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37];
                
            app.VariableDropDown.Items = sensitvityData;
            app.VariableDropDown.ItemsData = DropDownData;
            
            app.VariableDropDown_2.Items = sensitvityData;
            app.VariableDropDown_2.ItemsData = DropDownData;
            
            app.xaxisDropDown_2.Items = ['t_tot' sensitvityData 'Aero Efficiency c_L/c_W'];
            app.xaxisDropDown_2.ItemsData = [DropDownData (max(DropDownData)+1)];
            app.yaxisDropDown_2.Items = ['t_tot' sensitvityData 'Aero Efficiency c_L/c_W'];
            app.yaxisDropDown_2.ItemsData = [DropDownData (max(DropDownData)+1)];
            app.zaxisDropDown_2.Items = ['t_tot' sensitvityData 'Aero Efficiency c_L/c_W'];
            app.zaxisDropDown_2.ItemsData = [DropDownData (max(DropDownData)+1)];
            
            % Contains the names of all plots from the 'PlotResults.m' file
            % for a single run ordered by the number.
            plotSelect = {'Cell Current' 1; 'Slip' 2; 'Power Map (P_M)' 3; 'RPM' 4; 'Brake' 5; 'Tire Load Front' 6; 'Tire Load Rear' 7; 'Axle Load' 8;...
                'Dynamic Tire Radius' 9; 'Lateral Forces Front' 10; 'Lateral Forces Rear' 11; 'Conti Plot' 12; 'Tractive Power Rear' 13; 'Traction Limit'...
                14; 'Draw Track' 15; 'Used Energy' 16; 'g-g-V Plot' 17; 'Velocity m/s' 18; 'Velocity km/h' 19; 'Aero Tire Load' 20; 'Motor Efficency' 21;...
                'Acceleration m/s' 22; 'Power Map (P_el)' 23; 'P_el' 24; 'Lateral Acceleration Track' 25; 'Longitudinal Acceleration Track' 26; 'Acceleration g' 27;...
                'Accelerations Plot' 28; 'Virtual Current Cellpack' 29; 'Accumulator Voltage' 30; 'P_tractive' 31; 'Velocity Map' 32; 'DRS Status Map' 33;...
                'Track Analyzer' 34; 'RPM Map' 35; 'Torque Map' 36; 'Torque Plot' 37; 'Gearselection Map' 38; 'Gearselection Plot' 39; 'Steering Angle Plot' 40;...
                'Sideslip Angle Plot' 41; 'Slip Angle Plot' 42; 'Brake Velocity' 43};
            
            % Sorts the table alphabeticaly 
            plotSelect = sortrows(plotSelect, 1);
            
            % Sets the content of the plotSelect dropdown with the sorted
            % data
            app.SelectPlotDropDown.ItemsData = plotSelect(:,2);       
            app.SelectPlotDropDown.Items = plotSelect(:,1);
            
             % ToDo Change position of gearselection
            
            % Disable Options before data is loaded
            app.DataInspectorPanel.Visible = 'off';
            app.Load2RunButton.Enable = 'off';
            app.DrawPlot1RunButton.Enable = 'off';
            app.DrawPlot2RunButton.Enable = 'off';
            app.CompareRunPlotsButton.Enable = 'off';
            app.DrawSensitivityPlotButton.Enable = 'off';
            
            updateTracks(app);
            
            % Update the Color Scheme of the App
            updateColorScheme(app)
            
            % Renders the selected track in the Simulation Setup
            renderSelectedTrack(app);          
        end

        % Button pushed function: SuspensionButton
        function SuspensionButtonPushed(app, event)
            app.SuspensionButton.Enable = 'off';
            
            app.SuspensionApp = Suspension(app);
        end

        % Button pushed function: ChassisButton
        function ChassisButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ChassisButton.Enable = 'off';
            
            % starts the chassis options app
            app.ChassisApp = Chassis(app);
        end

        % Button pushed function: DrivetrainButton
        function DrivetrainButtonPushed(app, event)
            app.DrivetrainButton.Enable = 'off';
            
            app.DrivetrainApp = Drivetrain(app);
        end

        % Button pushed function: EnvironmentButton
        function EnvironmentButtonPushed(app, event)
            app.EnvironmentButton.Enable = 'off';
            
            app.EnvironmentApp = Environment(app);
        end

        % Button pushed function: AerodynamikButton
        function AerodynamikButtonPushed(app, event)
            app.AerodynamikButton.Enable = 'off';
            
            app.AerodynamikApp = Aerodynamik(app);
        end

        % Button pushed function: AccumulatorButton
        function AccumulatorButtonPushed(app, event)
            app.AccumulatorButton.Enable = 'off';
            
            app.AccumulatorApp = Accumulator(app);
        end

        % Close request function: LaptimeSimulationUIFigure
        function LaptimeSimulationUIFigureCloseRequest(app, event)
            app.settings.ColorsDark = app.ColorsDark;
            app.settings.ColorsBright = app.ColorsBright;
            
            settings = app.settings; %#ok<ADPROPLC> 
            save('Settings.mat','-struct','settings')
            
            % deletes app           
            delete(app.ChassisApp);
            delete(app.AccumulatorApp);
            delete(app.AerodynamikApp);
            delete(app.EnvironmentApp);
            delete(app.DrivetrainApp);
            delete(app.SuspensionApp);
            delete(app.TrackAnalyzerApp);
            delete(app.TelemetryConverterApp);
            delete(app.CustomizationApp)
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveData(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            try
                [file,path] = uigetfile('*.mat');  %open a mat file
                
                % Adds search path of the setup file and then loads the setup.
                addpath(path);                  
                
                loadSetup(app, file);
            catch
            end   
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            % Resets the Simulation status
            clearSimulationStatus(app);
            
            [carDatafile, path] = uigetfile('*.mat');  %open a mat file
            
            % Gets the current value of the track and discipline
            % DropDown
            startingParameters.disciplineID = str2double(app.DisciplineDropDown.Value);    
            startingParameters.carDatafile = carDatafile;
            startingParameters.path = path;
            startingParameters.TrackFileName = app.TrackFileName;
            startingParameters.brakeFunction = app.UseoldbrakesystemCheckBox.Value;
            startingParameters.logCellData = app.LogCellDataCheckBox.Value;
            startingParameters.debugMessages = app.EnableDebugMessagestimeintensivCheckBox.Value;
            startingParameters.processDataButtonHandle = app.StartSimulationButton;
            startingParameters.textAreaHandle = app.TextArea;
            startingParameters.startingSpeed = app.StartingSpeed;
            startingParameters.numOfLaps = app.numOfLaps;
            startingParameters.Debug = 0;
            
            % Check if Sensitivity Analysis is selected
            if (app.sensitivityCheckBox.Value)
                startingParameters.sensitivityID = app.VariableDropDown.Value;
                startingParameters.minValue = app.MinEditField.Value;
                startingParameters.stepSize = app.StepsizeEditField.Value;
                startingParameters.numSteps = app.NumStepsEditField.Value;
                
                if (app.secondVariableCheckBox.Value)
                    startingParameters.sensitivityID2 = app.VariableDropDown_2.Value;
                    startingParameters.minValue2 = app.MinEditField.Value;
                    startingParameters.stepSize2 = app.StepsizeEditField_2.Value;
                end
            else
                startingParameters.sensitivityID = 0;
                startingParameters.minValue = 0;
                startingParameters.stepSize = 0;
                startingParameters.numSteps = 1;
                startingParameters.sensitivityID2 = 0;
                startingParameters.minValue2 = 0;
                startingParameters.stepSize2 = 0;
            end
            
            simulationManager(startingParameters);   
        end

        % Button pushed function: DrawPlot1RunButton
        function DrawPlot1RunButtonPushed(app, event)
            plotID = app.SelectPlotDropDown.Value;
            runID = app.RunnumberEditField.Value;
            % Plots the Plot with the given ID and the given result file;
            
            if checkRunID(app, runID, app.result)
                return;
            end
            
            PlotResults(app.file_run_1,plotID,runID,1);
        end

        % Button pushed function: Load1RunButton
        function Load1RunButtonPushed(app, event)
            try
                [app.file_run_1, path] = uigetfile('*.mat');  %open a mat file
                
                
                % Adds search path of the setup file and then loads the setup.
                app.result  = load(path+"/"+app.file_run_1, '-mat');
                
                try 
                    % Checks if the track file is 4 or 5 columns and read it acordingly          
                    [~, columns] = size(app.result(1).Track);
                catch 
                    app.result = app.result.result;
                    
                    % Checks if the track file is 4 or 5 columns and read it acordingly          
                    [~, columns] = size(app.result(1).Track);
                end


                if (columns == 4)
                    app.x_Track = app.result(1).Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track = app.result(1).Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track = [];                              % [m] Z-Koordinate der Strecke
                    app.s = app.result(1).Track(:,3);                 % [m] Verlauf der Streckenlänge
                    app.R = app.result(1).Track(:,4);                 % [m] Kurvenradien
                else
                    app.x_Track = app.result(1).Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track = app.result(1).Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track = app.result(1).Track(:,3);           % [m] Z-Koordinate der Strecke
                    app.s = app.result(1).Track(:,4);                 % [m] Verlauf der Streckenlänge
                    app.R = app.result(1).Track(:,5);                 % [m] Kurvenradien
                end          
                
                % app.t_ges = app.result.t(end) *(22000/app.s(end));
                
                %% Set Result Labels
                % Lap time Label
                app.SkidPadTimeLabel.Text = "SkidPad Time = " + app.result(1).t_skidpad(1) + " s";
                app.SkidPadSpeedLabel.Text  = "SkidPad Speed = " + app.result(1).vV_skidpad(1) * 3.6 + " km/h";
                
                if app.result(1).disciplineID == 1
                    app.t_lapLabel.Text = "Lap time = " + app.result(1).t(end) + " s";
                else
                    %lapLength=length(Track)/app.result.numOfLaps;
                    app.t_lapLabel.Text = "Lap time = " + app.result(1).t(app.result(1).lapLength) + " s";
                end
                
                % Endurance time Label
                if app.result(1).disciplineID == 1
                    app.EndurancetimeLabel.Text = "Endurance time = -";
                else
                    app.EndurancetimeLabel.Text = "Endurance time = " + app.result(1).t(end) + " s";
                end
                
                % Lap Distance Label
                app.LapDistanceLabel.Text = "Lap Distance = " + app.s(app.result(1).lapLength) + " m";
                
                % Endurance Distance Label
                if app.result(1).disciplineID == 1
                    app.EnduranceDistanceLabel.Text = "Endurance Distance = -";
                else
                    app.EnduranceDistanceLabel.Text = "Endurance Distance = " + app.s(end) + " m";
                end       
                
                if app.result(1).ptype
                    % E Accumulator w/o recu
                    app.EnergyconsumptionLabel.Text = "Energy consumption =  " + app.result(1).E_Accu(end) + " kWh";
                    
                    % E Accumulator w recu
                    app.withrecuperationLabel.Text = "With recuperation =  " + app.result(1).E_res(end) + " kWh";
                else
                    % E Accumulator w/o recu
                    app.EnergyconsumptionLabel.Text = "Energy consumption = -";
                    
                    % E Accumulator w recu
                    app.withrecuperationLabel.Text = "With recuperation = -";
                end
                    
                % Computation Time
                 if (length(app.result(1).tEnd) > 1)
                     app.ComputationtimeLabel.Text = "Computation time = " + app.result(1).tEnd(end) + " s" + " (Ø " + app.result(1).tEnd(end)/length(app.result(1).tEnd) + " s)";
                 else
                     app.ComputationtimeLabel.Text = "Computation time = " + app.result(1).tEnd(end) + " s";
                 end
                
                % Powerlimit
                if app.result(1).ptype
                    app.PowerLimitLabel.Text = "Power Limit = " + app.result(1).p_max(1) + " kW";
                else
                    app.PowerLimitLabel.Text = "Power Limit = -";
                end
                 
                % Enables button for second run and draws panel
                app.DataInspectorPanel.Visible = 'on';
                app.Load2RunButton.Enable = 'on';
                app.DrawPlot1RunButton.Enable = 'on';
                app.DrawSensitivityPlotButton.Enable = 'on';
                
                if (~isnan(app.file_run_2))
                    % Compares both runs in the third panel (Run_2-Run_1)
                    app.compareRuns;
                end
            catch error
                writeToLogfile(error.message);
            end
        end

        % Button pushed function: Load2RunButton
        function Load2RunButtonPushed(app, event)
            try
                [app.file_run_2, path] = uigetfile('*.mat');  %open a mat file
                
                % Adds search path of the setup file and then loads the setup.
                app.result_2  = load(path+"/"+app.file_run_2, '-mat');
                
                % Checks if the track file is 4 or 5 columns and read it acordingly          
                [~, columns] = size(app.result_2(1).Track);
                
                if (columns == 4)
                    app.x_Track_2 = app.result_2(1).Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track_2 = app.result_2(1).Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track_2 = [];                              % [m] Z-Koordinate der Strecke
                    app.s_2 = app.result_2(1).Track(:,3);                 % [m] Verlauf der Streckenlänge
                    app.R_2 = app.result_2(1).Track(:,4);                 % [m] Kurvenradien
                else
                    app.x_Track_2 = app.result_2(1).Track(:,1);           % [m] X-Koordinate der Strecke
                    app.y_Track_2 = app.result_2(1).Track(:,2);           % [m] Y-Koordinate der Strecke
                    app.z_Track_2 = app.result_2(1).Track(:,3);           % [m] Z-Koordinate der Strecke
                    app.s_2 = app.result_2(1).Track(:,4);                 % [m] Verlauf der Streckenlänge
                    app.R_2 = app.result_2(1).Track(:,5);                 % [m] Kurvenradien
                end    
                
                %app.t_ges_2 = app.result_2.t(end) *(22000/app.s_2(end));
                
                %% Set Result Labels
                % Lap time Label
                app.SkidPadTimeLabel_2.Text  = "SkidPad Time = " + app.result_2(1).t_skidpad(1) + " s";
                app.SkidPadSpeedLabel_2.Text  = "SkidPad Speed = " + app.result_2(1).vV_skidpad(1) * 3.6 + " km/h";
                
                if app.result_2(1).disciplineID == 1
                    app.t_lapLabel_2.Text = "Lap time = " + app.result_2(1).t(end) + " s";
                else
                    %lapLength=length(Track)/app.result.numOfLaps;
                    app.t_lapLabel_2.Text = "Lap time = " + app.result_2(1).t(app.result_2(1).lapLength) + " s";
                end
                
                % Endurance time Label
                if app.result_2(1).disciplineID == 1
                    app.EndurancetimeLabel_2.Text = "Endurance time = -";
                else
                    app.EndurancetimeLabel_2.Text = "Endurance time = " + app.result_2(1).t(end) + " s";
                end
                
                % Lap Distance Label
                app.LapDistanceLabel_2.Text = "Lap Distance = " + app.s_2(app.result_2(1).lapLength) + " m";
                
                % Endurance Distance Label
                if app.result_2(1).disciplineID == 1
                    app.EnduranceDistanceLabel_2.Text = "Endurance Distance = -";
                else
                    app.EnduranceDistanceLabel_2.Text = "Endurance Distance = " + app.s_2(end) + " m";
                end       
                
                if app.result_2(1).ptype
                    % E Accumulator w/o recu
                    app.EnergyconsumptionLabel_2.Text = "Energy consumption =  " + app.result_2(1).E_Accu(end) + " kWh";
                    
                    % E Accumulator w recu
                    app.withrecuperationLabel_2.Text = "With recuperation =  " + app.result_2(1).E_res(end) + " kWh";
                else
                    % E Accumulator w/o recu
                    app.EnergyconsumptionLabel_2.Text = "Energy consumption = -";
                    
                    % E Accumulator w recu
                    app.withrecuperationLabel_2.Text = "With recuperation = -";
                end
                
                % Computation Time
                 if (length(app.result_2(1).tEnd) > 1)
                     app.ComputationtimeLabel_2.Text = "Computation time = " + app.result_2(1).tEnd(end) + " s" + " (Ø " + app.result_2(1).tEnd(end)/length(app.result_2(1).tEnd) + " s)";
                 else
                     app.ComputationtimeLabel_2.Text = "Computation time = " + app.result_2(1).tEnd(end) + " s";
                 end
                
                % Powerlimit
                if app.result_2(1).ptype
                    app.PowerLimitLabel_2.Text = "Power Limit = " + app.result_2(1).p_max(1) + " kW";
                else
                    app.PowerLimitLabel_2.Text = "Power Limit = -";
                end
                
                % Compares both runs in the third panel (Run_2-Run_1)
                app.compareRuns;
                
                app.DrawPlot2RunButton.Enable = 'on';
                app.CompareRunPlotsButton.Enable = 'on';
                app.CreateData2RunButton.Enable = 'on';
                app.LoadSetup2RunButton.Enable = 'on';
            catch error
                writeToLogfile(error.message);
            end 
        end

        % Button pushed function: DrawPlot2RunButton
        function DrawPlot2RunButtonPushed(app, event)
            % Plots the Plot with the given ID and the given result file
            plotID = app.SelectPlotDropDown.Value;
            runID = app.RunnumberEditField.Value;
            
            if checkRunID(app, runID, app.result_2)
%                 return;
            end
            
            PlotResults(app.file_run_2,plotID,runID,2); % Plots the Plot with the given ID and the given result file
        end

        % Button pushed function: CompareRunPlotsButton
        function CompareRunPlotsButtonPushed(app, event)
            plotID = str2double(app.SelectPlotDropDown_2.Value);
            % Plots the Plot with the given ID and the given result file
            runID = app.RunnumberEditField.Value;
            PlotResults(app.file_run_1,plotID, runID,1,app.file_run_2);
        end

        % Value changed function: TrackDropDown
        function TrackDropDownValueChanged(app, event)
            % Change currently rendered track
            renderSelectedTrack(app);           
        end

        % Value changed function: sensitivityCheckBox
        function sensitivityCheckBoxValueChanged(app, event)
            %% Activate / Deactive Checkboxes for sensitivity analysis 
            if (app.sensitivityCheckBox.Value)
                app.VariableDropDown.Enable = true;
                app.VariableDropDownLabel.Enable = true;
                app.MinEditField.Enable = true;
                app.MinEditFieldLabel.Enable = true;
                app.NumStepsEditField.Enable = true;
                app.NumStepsEditFieldLabel.Enable = true;
                app.StepsizeEditField.Enable = true;
                app.StepsizeEditFieldLabel.Enable = true;
                app.secondVariableCheckBox.Enable = true;
                
                if (app.secondVariableCheckBox.Value) 
                    app.VariableDropDown_2.Enable = true;
                    app.MinEditField_2.Enable = true;
                    app.StepsizeEditField_2.Enable = true;
                end
            else
                app.VariableDropDown.Enable = false;
                app.VariableDropDownLabel.Enable = false;
                app.MinEditField.Enable = false;
                app.MinEditFieldLabel.Enable = false;
                app.NumStepsEditField.Enable = false;
                app.NumStepsEditFieldLabel.Enable = false;
                app.StepsizeEditField.Enable = false;
                app.StepsizeEditFieldLabel.Enable = false;
                app.secondVariableCheckBox.Enable = false;
                app.VariableDropDown_2.Enable = false;
                app.secondVariableCheckBox.Value = 0;
                app.MinEditField_2.Enable = false;
                app.StepsizeEditField_2.Enable = false;
            end
        end

        % Button pushed function: DrawSensitivityPlotButton
        function DrawSensitivityPlotButtonPushed(app, event)
            if app.zAxisCheckBox.Value
                PlotSensitivity(app.file_run_1, app.xaxisDropDown_2.Value, app.yaxisDropDown_2.Value, app.DrawDotsCheckBox.Value, app.zaxisDropDown_2.Value, app.ResolutionxEditField.Value, app.ResolutionyEditField.Value);
            else 
                PlotSensitivity(app.file_run_1, app.xaxisDropDown_2.Value, app.yaxisDropDown_2.Value, app.DrawDotsCheckBox.Value);
            end        
        end

        % Button pushed function: DailyUpdateFeatureRequestsButton
        function DailyUpdateFeatureRequestsButtonPushed(app, event)
            web('https://docs.google.com/document/d/1QEmcAvjUyt6PL1MTbRToHqxlbFu-7Gm8/edit');
        end

        % Image clicked function: Image2, Image2_2
        function Image2Clicked(app, event)
            web('https://balticracing.hochschule-stralsund.de/');
        end

        % Image clicked function: Image, Image_2
        function ImageClicked(app, event)
            web('https://www.hochschule-stralsund.de/');
        end

        % Button pushed function: ContactmeButton
        function ContactmeButtonPushed(app, event)
            web mailto:e.dornieden@web.de;
        end

        % Value changed function: secondVariableCheckBox
        function secondVariableCheckBoxValueChanged(app, event)

            if (app.secondVariableCheckBox.Value)
                app.VariableDropDown_2.Enable = true;
                app.MinEditField_2.Enable = true;
                app.StepsizeEditField_2.Enable = true;
            else
                app.VariableDropDown_2.Enable = false;
                app.MinEditField_2.Enable = false;
                app.StepsizeEditField_2.Enable = false;
            end
        end

        % Value changed function: zAxisCheckBox
        function zAxisCheckBoxValueChanged(app, event)
            if app.zAxisCheckBox.Value
                app.zaxisDropDown_2.Enable = true;
                app.ResolutionxEditField.Enable = true;
                app.ResolutionyEditField.Enable = true;
            else
                app.zaxisDropDown_2.Enable = false;
                app.ResolutionxEditField.Enable = false;
                app.ResolutionyEditField.Enable = false;
            end
        end

        % Button pushed function: LoadReplayButton
        function LoadReplayButtonPushed(app, event)
            try
                [app.file_run_3, ~] = uigetfile('*.mat');  %open a mat file
        
                app.result_3 = load(app.file_run_3, '-mat');
                

                plot(app.UIAxes3,app.result_3.Data.Track(:,1),app.result_3.Data.Track(:,2),'k')
                title(app.UIAxes3,'','FontSize',12);
                app.UIAxes2.LineWidth = 1.5;
            catch
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Resets the simulation status
            clearSimulationStatus(app);
        end

        % Button pushed function: WikiBalticRacingButton
        function WikiBalticRacingButtonPushed(app, event)
            web('https://balticracing.hochschule-stralsund.de/wiki/index.php/Hauptseite');
        end

        % Button pushed function: LaptimeSimulationGitHubButton
        function LaptimeSimulationGitHubButtonPushed(app, event)
            web('https://github.com/baltic-racing/Lap_Time_Simulation');
        end

        % Button pushed function: OpenDataInspectorButton
        function OpenDataInspectorButtonPushed(app, event)
            if ~isdeployed      % Check if Simulation model is installed or used directly from matlab (Simulink Data Inspector is not compatible with compiled versions)         
                Simulink.sdi.view;
            else 
                uialert(app.LaptimeSimulationUIFigure,'Simulink.sdi.view is not supported','Simulink Error')
            end
        end

        % Button pushed function: CreateData1RunButton
        function CreateData1RunButtonPushed(app, event)
            % Creates an .csv file with all the generated simulation data
            % and then creates an file for the Data inspector from it.
            createRunObject(app, app.result, 1);
        end

        % Button pushed function: CreateData2RunButton
        function CreateData2RunButtonPushed(app, event)
            % Creates an .csv file with all the generated simulation data
            % and then creates an file for the Data inspector from it.
            createRunObject(app, app.result_2, 2);
        end

        % Button pushed function: LoadSetup1RunButton
        function LoadSetup1RunButtonPushed(app, event)
            displaySetupData(app, app.result);
        end

        % Button pushed function: LoadSetup2RunButton
        function LoadSetup2RunButtonPushed(app, event)
            displaySetupData(app, app.result_2);
        end

        % Button pushed function: OpenTrackAnalyzerButton
        function OpenTrackAnalyzerButtonPushed(app, event)
            %app.TrackAnalyzerApp.Enable = 'off';
            
            app.TrackAnalyzerApp = TrackAnalyzer(app);
        end

        % Button pushed function: TelemetryconverterButton
        function TelemetryconverterButtonPushed(app, event)
            app.TelemetryConverterApp = TelemetryConverter(app);
        end

        % Button pushed function: TrackManagerButton
        function TrackManagerButtonPushed(app, event)
            app.TrackManagerButton.Enable = 'off';
            
            app.TrackManagerApp = TrackManager(app);
        end

        % Button pushed function: UpdateTracksButton
        function UpdateTracksButtonPushed(app, event)
            updateTracks(app);
        end

        % Value changed function: DarkModeCheckBox
        function DarkModeCheckBoxValueChanged(app, event)
            if app.DarkModeCheckBox.Value
                app.ColorSchemeID = 1;
                app.settings.ColorScheme = 1;
            else
                app.ColorSchemeID = 0;
                app.settings.ColorScheme = 0;
            end
            
            updateColorScheme(app);
            renderSelectedTrack(app)
        end

        % Button pushed function: NoDownforceButton
        function NoDownforceButtonPushed(app, event)
            loadSetup(app, 'TY19_NoDownforce.mat');
        end

        % Button pushed function: LowDownforceButton
        function LowDownforceButtonPushed(app, event)
            loadSetup(app, 'TY19_LowDownforce.mat');
        end

        % Button pushed function: MedDownforceButton
        function MedDownforceButtonPushed(app, event)
            loadSetup(app, 'TY19_MedDownforce.mat');
        end

        % Button pushed function: HighDownforceButton
        function HighDownforceButtonPushed(app, event)
            loadSetup(app, 'TY19_HighDownforce.mat');
        end

        % Button pushed function: NoDownforceButton_2
        function NoDownforceButton_2Pushed(app, event)
            loadSetup(app, 'TY20_NoDownforce.mat');
        end

        % Button pushed function: LowDownforceButton_2
        function LowDownforceButton_2Pushed(app, event)
            loadSetup(app, 'TY20_LowDownforce.mat');
        end

        % Button pushed function: MedDownforceButton_2
        function MedDownforceButton_2Pushed(app, event)
            loadSetup(app, 'TY20_MedDownforce.mat');
        end

        % Button pushed function: HighDownforceButton_2
        function HighDownforceButton_2Pushed(app, event)
            loadSetup(app, 'TY20_HighDownforce.mat');
        end

        % Button pushed function: NoDownforceButton_3
        function NoDownforceButton_3Pushed(app, event)
            loadSetup(app, 'TY22_NoDownforce.mat');
        end

        % Button pushed function: LowDownforceButton_3
        function LowDownforceButton_3Pushed(app, event)
            loadSetup(app, 'TY22_LowDownforce.mat');
        end

        % Button pushed function: MedDownforceButton_3
        function MedDownforceButton_3Pushed(app, event)
            loadSetup(app, 'TY22_MedDownforce.mat');
        end

        % Button pushed function: HighDownforceButton_3
        function HighDownforceButton_3Pushed(app, event)
            loadSetup(app, 'TY22_HighDownforce.mat');
        end

        % Button pushed function: StyleSettingsButton
        function StyleSettingsButtonPushed(app, event)
            app.StyleSettingsButton.Enable = 'off';
            
            app.CustomizationApp = Customization(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaptimeSimulationUIFigure = uifigure('Visible', 'off');
            app.LaptimeSimulationUIFigure.Name = 'Laptime Simulation';
            app.LaptimeSimulationUIFigure.Icon = 'Baltic.png';
            app.LaptimeSimulationUIFigure.CloseRequestFcn = createCallbackFcn(app, @LaptimeSimulationUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.LaptimeSimulationUIFigure);
            app.HowtoTab = uitab(app.TabGroup);
            app.HowtoTab.Title = 'How-to';
            app.HowToLaptimeSimulationPanel = uipanel(app.HowtoTab);
            app.HowToLaptimeSimulationPanel.Title = 'How-To Laptime Simulation';
            app.EditthecarsetupintheCarSetuptab2SaveCarSetupLabel = uilabel(app.HowToLaptimeSimulationPanel);
            app.EditthecarsetupintheCarSetuptab2SaveCarSetupLabel.Text = {'1. Load a Setup or use the baseline setup in the ''Car Setup'' tab'; '2. Edit the car setup if needed'; '3. Save the car setup'; '4. In the ''Simulation Setup'' tab change the desired mission and activate sensitivity analysis if needed'; '5. Click on the ''Start Simulation'' button and the select the desired setup file'; '6. The simulation will start and a ''_result'' file with the same name as the setup will be created'; '7. The result file can now be opened in the ''Simulation Results'' tab there you can load up to two result'; 'files and draw plots for both of them and compare them'};
            app.DailyUpdateFeatureRequestsButton = uibutton(app.HowtoTab, 'push');
            app.DailyUpdateFeatureRequestsButton.ButtonPushedFcn = createCallbackFcn(app, @DailyUpdateFeatureRequestsButtonPushed, true);
            app.DailyUpdateFeatureRequestsButton.Text = 'Daily Update / Feature Requests';
            app.Copyright2021BalticRacingbyEricDorniedenLabel = uilabel(app.HowtoTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image = uiimage(app.HowtoTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Logo_Ship_HKS_Ranged.png';
            app.Image2 = uiimage(app.HowtoTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'balticracing_logo_transparent.png';
            app.ContactmeButton = uibutton(app.HowtoTab, 'push');
            app.ContactmeButton.ButtonPushedFcn = createCallbackFcn(app, @ContactmeButtonPushed, true);
            app.ContactmeButton.Text = 'Contact me';
            app.PatchNotesPanel = uipanel(app.HowtoTab);
            app.PatchNotesPanel.Title = 'Patch Notes';
            app.TextArea = uitextarea(app.PatchNotesPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'--------------------------------------------------------------------------------------------------------------------------------------------------------------'; '--------------------------------------------------------------------Laptime Simulation-----------------------------------------------------------------'; '--------------------------------------------------------------------------------------------------------------------------------------------------------------'; ''; '--------------------------------------------------------------------------------------------------------------------------------------------------------------'};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
            app.WikiBalticRacingButton = uibutton(app.HowtoTab, 'push');
            app.WikiBalticRacingButton.ButtonPushedFcn = createCallbackFcn(app, @WikiBalticRacingButtonPushed, true);
            app.WikiBalticRacingButton.Text = 'Wiki Baltic Racing';
            app.LaptimeSimulationGitHubButton = uibutton(app.HowtoTab, 'push');
            app.LaptimeSimulationGitHubButton.ButtonPushedFcn = createCallbackFcn(app, @LaptimeSimulationGitHubButtonPushed, true);
            app.LaptimeSimulationGitHubButton.Text = 'Laptime Simulation GitHub';
            app.DarkModeCheckBox = uicheckbox(app.HowtoTab);
            app.DarkModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DarkModeCheckBoxValueChanged, true);
            app.DarkModeCheckBox.Text = 'Dark Mode';
            app.StyleSettingsButton = uibutton(app.HowtoTab, 'push');
            app.StyleSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @StyleSettingsButtonPushed, true);
            app.StyleSettingsButton.Text = 'Style Settings';
            app.CarSetupTab = uitab(app.TabGroup);
            app.CarSetupTab.Title = 'Car Setup';
            app.Copyright2021BalticRacingbyEricDorniedenLabel_2 = uilabel(app.CarSetupTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel_2.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image2_2 = uiimage(app.CarSetupTab);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2_2.ImageSource = 'balticracing_logo_transparent.png';
            app.EditSetupPanel = uipanel(app.CarSetupTab);
            app.EditSetupPanel.Title = 'Edit Setup';
            app.CarParametersPanel = uipanel(app.EditSetupPanel);
            app.CarParametersPanel.Title = 'Car Parameters';
            app.SuspensionButton = uibutton(app.CarParametersPanel, 'push');
            app.SuspensionButton.ButtonPushedFcn = createCallbackFcn(app, @SuspensionButtonPushed, true);
            app.SuspensionButton.Text = 'Suspension';
            app.ChassisButton = uibutton(app.CarParametersPanel, 'push');
            app.ChassisButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisButtonPushed, true);
            app.ChassisButton.Text = 'Chassis';
            app.DrivetrainButton = uibutton(app.CarParametersPanel, 'push');
            app.DrivetrainButton.ButtonPushedFcn = createCallbackFcn(app, @DrivetrainButtonPushed, true);
            app.DrivetrainButton.Text = 'Drivetrain';
            app.AerodynamikButton = uibutton(app.CarParametersPanel, 'push');
            app.AerodynamikButton.ButtonPushedFcn = createCallbackFcn(app, @AerodynamikButtonPushed, true);
            app.AerodynamikButton.Text = 'Aerodynamik';
            app.AccumulatorButton = uibutton(app.CarParametersPanel, 'push');
            app.AccumulatorButton.ButtonPushedFcn = createCallbackFcn(app, @AccumulatorButtonPushed, true);
            app.AccumulatorButton.Text = 'Accumulator';
            app.EnviromentPanel = uipanel(app.EditSetupPanel);
            app.EnviromentPanel.Title = 'Enviroment';
            app.EnvironmentButton = uibutton(app.EnviromentPanel, 'push');
            app.EnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentButtonPushed, true);
            app.EnvironmentButton.Text = 'Environment';
            app.LoadButton = uibutton(app.EditSetupPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.EditSetupPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SetupPresetsPanel = uipanel(app.EditSetupPanel);
            app.SetupPresetsPanel.Title = 'Setup Presets';
            app.TY19Panel = uipanel(app.SetupPresetsPanel);
            app.TY19Panel.Title = 'TY-19';
            app.LowDownforceButton = uibutton(app.TY19Panel, 'push');
            app.LowDownforceButton.ButtonPushedFcn = createCallbackFcn(app, @LowDownforceButtonPushed, true);
            app.LowDownforceButton.Text = 'Low Downforce';
            app.MedDownforceButton = uibutton(app.TY19Panel, 'push');
            app.MedDownforceButton.ButtonPushedFcn = createCallbackFcn(app, @MedDownforceButtonPushed, true);
            app.MedDownforceButton.Text = 'Med. Downforce';
            app.HighDownforceButton = uibutton(app.TY19Panel, 'push');
            app.HighDownforceButton.ButtonPushedFcn = createCallbackFcn(app, @HighDownforceButtonPushed, true);
            app.HighDownforceButton.Text = 'High Downforce';
            app.NoDownforceButton = uibutton(app.TY19Panel, 'push');
            app.NoDownforceButton.ButtonPushedFcn = createCallbackFcn(app, @NoDownforceButtonPushed, true);
            app.NoDownforceButton.Text = 'No Downforce';
            app.TY20Panel = uipanel(app.SetupPresetsPanel);
            app.TY20Panel.Title = 'TY-20';
            app.LowDownforceButton_2 = uibutton(app.TY20Panel, 'push');
            app.LowDownforceButton_2.ButtonPushedFcn = createCallbackFcn(app, @LowDownforceButton_2Pushed, true);
            app.LowDownforceButton_2.Text = 'Low Downforce';
            app.MedDownforceButton_2 = uibutton(app.TY20Panel, 'push');
            app.MedDownforceButton_2.ButtonPushedFcn = createCallbackFcn(app, @MedDownforceButton_2Pushed, true);
            app.MedDownforceButton_2.Text = 'Med. Downforce';
            app.HighDownforceButton_2 = uibutton(app.TY20Panel, 'push');
            app.HighDownforceButton_2.ButtonPushedFcn = createCallbackFcn(app, @HighDownforceButton_2Pushed, true);
            app.HighDownforceButton_2.Text = 'High Downforce';
            app.NoDownforceButton_2 = uibutton(app.TY20Panel, 'push');
            app.NoDownforceButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoDownforceButton_2Pushed, true);
            app.NoDownforceButton_2.Text = 'No Downforce';
            app.TY22Panel = uipanel(app.SetupPresetsPanel);
            app.TY22Panel.Title = 'TY-22';
            app.LowDownforceButton_3 = uibutton(app.TY22Panel, 'push');
            app.LowDownforceButton_3.ButtonPushedFcn = createCallbackFcn(app, @LowDownforceButton_3Pushed, true);
            app.LowDownforceButton_3.Text = 'Low Downforce';
            app.MedDownforceButton_3 = uibutton(app.TY22Panel, 'push');
            app.MedDownforceButton_3.ButtonPushedFcn = createCallbackFcn(app, @MedDownforceButton_3Pushed, true);
            app.MedDownforceButton_3.Text = 'Med. Downforce';
            app.HighDownforceButton_3 = uibutton(app.TY22Panel, 'push');
            app.HighDownforceButton_3.ButtonPushedFcn = createCallbackFcn(app, @HighDownforceButton_3Pushed, true);
            app.HighDownforceButton_3.Text = 'High Downforce';
            app.NoDownforceButton_3 = uibutton(app.TY22Panel, 'push');
            app.NoDownforceButton_3.ButtonPushedFcn = createCallbackFcn(app, @NoDownforceButton_3Pushed, true);
            app.NoDownforceButton_3.Text = 'No Downforce';
            app.VariableListButton = uibutton(app.EditSetupPanel, 'push');
            app.VariableListButton.Text = 'Variable List';
            app.Image_2 = uiimage(app.CarSetupTab);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_2.ImageSource = 'Logo_Ship_HKS_Ranged.png';
            app.SimulationSetupTab = uitab(app.TabGroup);
            app.SimulationSetupTab.Title = 'Simulation Setup';
            app.UIAxes2 = uiaxes(app.SimulationSetupTab);
            title(app.UIAxes2, 'Title')
            app.StartSimulationButton = uibutton(app.SimulationSetupTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.SensitivityAnalysisPanel = uipanel(app.SimulationSetupTab);
            app.SensitivityAnalysisPanel.Title = 'Sensitivity Analysis';
            app.VariableDropDownLabel = uilabel(app.SensitivityAnalysisPanel);
            app.VariableDropDownLabel.Enable = 'off';
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.SensitivityAnalysisPanel);
            app.VariableDropDown.Enable = 'off';
            app.MinEditFieldLabel = uilabel(app.SensitivityAnalysisPanel);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.MinEditField.Enable = 'off';
            app.StepsizeEditFieldLabel = uilabel(app.SensitivityAnalysisPanel);
            app.StepsizeEditFieldLabel.Enable = 'off';
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.StepsizeEditField.Enable = 'off';
            app.sensitivityCheckBox = uicheckbox(app.SensitivityAnalysisPanel);
            app.sensitivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @sensitivityCheckBoxValueChanged, true);
            app.sensitivityCheckBox.Text = 'activated';
            app.NumStepsEditFieldLabel = uilabel(app.SensitivityAnalysisPanel);
            app.NumStepsEditFieldLabel.Enable = 'off';
            app.NumStepsEditFieldLabel.Text = 'Num Steps';
            app.NumStepsEditField = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.NumStepsEditField.Limits = [1 Inf];
            app.NumStepsEditField.RoundFractionalValues = 'on';
            app.NumStepsEditField.Enable = 'off';
            app.NumStepsEditField.Value = 1;
            app.VariableDropDown_2 = uidropdown(app.SensitivityAnalysisPanel);
            app.VariableDropDown_2.Enable = 'off';
            app.secondVariableCheckBox = uicheckbox(app.SensitivityAnalysisPanel);
            app.secondVariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @secondVariableCheckBoxValueChanged, true);
            app.secondVariableCheckBox.Enable = 'off';
            app.secondVariableCheckBox.Text = '2. Variable';
            app.MinEditField_2 = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.MinEditField_2.Enable = 'off';
            app.StepsizeEditField_2 = uieditfield(app.SensitivityAnalysisPanel, 'numeric');
            app.StepsizeEditField_2.Enable = 'off';
            app.ScenarioPanel = uipanel(app.SimulationSetupTab);
            app.ScenarioPanel.Title = 'Scenario';
            app.TrackDropDownLabel = uilabel(app.ScenarioPanel);
            app.TrackDropDownLabel.Text = 'Track';
            app.TrackDropDown = uidropdown(app.ScenarioPanel);
            app.TrackDropDown.Items = {'FSG Endurance', 'FSG AutoX', 'Testtrack', 'Accel', 'SkidPad', 'FSA Endurance 2021'};
            app.TrackDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.TrackDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackDropDownValueChanged, true);
            app.TrackDropDown.Value = '1';
            app.DisciplineDropDownLabel = uilabel(app.ScenarioPanel);
            app.DisciplineDropDownLabel.Text = 'Discipline';
            app.DisciplineDropDown = uidropdown(app.ScenarioPanel);
            app.DisciplineDropDown.Items = {'AutoX', 'Endurance'};
            app.DisciplineDropDown.ItemsData = {'1', '2'};
            app.DisciplineDropDown.Value = '1';
            app.UpdateTracksButton = uibutton(app.ScenarioPanel, 'push');
            app.UpdateTracksButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTracksButtonPushed, true);
            app.UpdateTracksButton.Text = 'Update Tracks';
            app.TrackManagerButton = uibutton(app.ScenarioPanel, 'push');
            app.TrackManagerButton.ButtonPushedFcn = createCallbackFcn(app, @TrackManagerButtonPushed, true);
            app.TrackManagerButton.Text = 'Track Manager';
            app.LengthLabel = uilabel(app.SimulationSetupTab);
            app.LengthLabel.Text = 'Length';
            app.SimulationStatusPanel = uipanel(app.SimulationSetupTab);
            app.SimulationStatusPanel.Title = 'Simulation Status';
            app.SimulationTextArea = uitextarea(app.SimulationStatusPanel);
            app.SimulationTextArea.Editable = 'off';
            app.SimulationTextArea.Value = {'The status of the current simulation is displayed here. (Only works when debug messages are enabled)'};
            app.ClearButton = uibutton(app.SimulationStatusPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Copyright2021BalticRacingbyEricDorniedenLabel_3 = uilabel(app.SimulationSetupTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel_3.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.EnableDebugMessagestimeintensivCheckBox = uicheckbox(app.SimulationSetupTab);
            app.EnableDebugMessagestimeintensivCheckBox.Tooltip = {'Write Debug Messages to the Simulation Status Screen (Calculation time increases significantly!)'};
            app.EnableDebugMessagestimeintensivCheckBox.Text = 'Enable Debug Messages (time intensiv)';
            app.LogCellDataCheckBox = uicheckbox(app.SimulationSetupTab);
            app.LogCellDataCheckBox.Tooltip = {'If activated cell data is saved to the log file. (Save file can get very large especially when using sensitivity analysis)'};
            app.LogCellDataCheckBox.Text = 'Log Cell Data';
            app.UseoldbrakesystemCheckBox = uicheckbox(app.SimulationSetupTab);
            app.UseoldbrakesystemCheckBox.Text = 'Use old brake system';
            app.RunUnitTestsButton = uibutton(app.SimulationSetupTab, 'push');
            app.RunUnitTestsButton.Text = 'Run Unit Tests';
            app.TrackDataOptimizationSpinnerLabel = uilabel(app.SimulationSetupTab);
            app.TrackDataOptimizationSpinnerLabel.Text = 'Track Data Optimization';
            app.TrackDataOptimizationSpinner = uispinner(app.SimulationSetupTab);
            app.TrackDataOptimizationSpinner.UpperLimitInclusive = 'off';
            app.TrackDataOptimizationSpinner.Limits = [0 Inf];
            app.TrackDataOptimizationSpinner.ValueDisplayFormat = '%.0f';
            app.SimulationResultsTab = uitab(app.TabGroup);
            app.SimulationResultsTab.Title = 'Simulation Results';
            app.Load1RunButton = uibutton(app.SimulationResultsTab, 'push');
            app.Load1RunButton.ButtonPushedFcn = createCallbackFcn(app, @Load1RunButtonPushed, true);
            app.Load1RunButton.Text = 'Load 1. Run';
            app.Results1RunPanel = uipanel(app.SimulationResultsTab);
            app.Results1RunPanel.Title = 'Results 1. Run';
            app.t_lapLabel = uilabel(app.Results1RunPanel);
            app.t_lapLabel.Text = 'Lap time =';
            app.EndurancetimeLabel = uilabel(app.Results1RunPanel);
            app.EndurancetimeLabel.Text = 'Endurance time =';
            app.LapDistanceLabel = uilabel(app.Results1RunPanel);
            app.LapDistanceLabel.Text = 'Lap Distance =';
            app.EnergyconsumptionLabel = uilabel(app.Results1RunPanel);
            app.EnergyconsumptionLabel.Text = 'Energy consumption  =';
            app.withrecuperationLabel = uilabel(app.Results1RunPanel);
            app.withrecuperationLabel.Text = 'with recuperation =';
            app.ComputationtimeLabel = uilabel(app.Results1RunPanel);
            app.ComputationtimeLabel.Text = 'Computation time:';
            app.PowerLimitLabel = uilabel(app.Results1RunPanel);
            app.PowerLimitLabel.Text = 'Power Limit =';
            app.EnduranceDistanceLabel = uilabel(app.Results1RunPanel);
            app.EnduranceDistanceLabel.Text = 'Endurance Distance =';
            app.SkidPadTimeLabel = uilabel(app.Results1RunPanel);
            app.SkidPadTimeLabel.Text = 'SkidPad Time =';
            app.SkidPadSpeedLabel = uilabel(app.Results1RunPanel);
            app.SkidPadSpeedLabel.Text = 'SkidPad Speed =';
            app.Load2RunButton = uibutton(app.SimulationResultsTab, 'push');
            app.Load2RunButton.ButtonPushedFcn = createCallbackFcn(app, @Load2RunButtonPushed, true);
            app.Load2RunButton.Text = 'Load 2. Run';
            app.DataInspectorPanel = uipanel(app.SimulationResultsTab);
            app.DataInspectorPanel.Title = 'Data Inspector';
            app.OpenDataInspectorButton = uibutton(app.DataInspectorPanel, 'push');
            app.OpenDataInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataInspectorButtonPushed, true);
            app.OpenDataInspectorButton.Text = 'Open Data Inspector';
            app.SelectLapRunnumberEditFieldLabel = uilabel(app.DataInspectorPanel);
            app.SelectLapRunnumberEditFieldLabel.Text = 'Select Lap / Run number';
            app.SelectLapRunnumberEditField = uieditfield(app.DataInspectorPanel, 'numeric');
            app.SelectLapRunnumberEditField.UpperLimitInclusive = 'off';
            app.SelectLapRunnumberEditField.Limits = [1 Inf];
            app.SelectLapRunnumberEditField.RoundFractionalValues = 'on';
            app.SelectLapRunnumberEditField.Value = 1;
            app.CreateData1RunButton = uibutton(app.DataInspectorPanel, 'push');
            app.CreateData1RunButton.ButtonPushedFcn = createCallbackFcn(app, @CreateData1RunButtonPushed, true);
            app.CreateData1RunButton.Text = 'Create Data 1. Run';
            app.CreateData2RunButton = uibutton(app.DataInspectorPanel, 'push');
            app.CreateData2RunButton.ButtonPushedFcn = createCallbackFcn(app, @CreateData2RunButtonPushed, true);
            app.CreateData2RunButton.Enable = 'off';
            app.CreateData2RunButton.Text = 'Create Data 2. Run';
            app.SetupviewerTextAreaLabel = uilabel(app.DataInspectorPanel);
            app.SetupviewerTextAreaLabel.Text = 'Setup viewer';
            app.SetupviewerTextArea = uitextarea(app.DataInspectorPanel);
            app.LoadSetup1RunButton = uibutton(app.DataInspectorPanel, 'push');
            app.LoadSetup1RunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSetup1RunButtonPushed, true);
            app.LoadSetup1RunButton.Text = 'Load Setup 1. Run';
            app.LoadSetup2RunButton = uibutton(app.DataInspectorPanel, 'push');
            app.LoadSetup2RunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSetup2RunButtonPushed, true);
            app.LoadSetup2RunButton.Enable = 'off';
            app.LoadSetup2RunButton.Text = 'Load Setup 2. Run';
            app.ResultfilenameEditFieldLabel = uilabel(app.DataInspectorPanel);
            app.ResultfilenameEditFieldLabel.Text = 'Result file name';
            app.ResultfilenameEditField = uieditfield(app.DataInspectorPanel, 'text');
            app.ResultfilenameEditField.Tooltip = {'Name for the generated save file. If left blank a generic name will be used.'};
            app.DeletecsvfileCheckBox = uicheckbox(app.DataInspectorPanel);
            app.DeletecsvfileCheckBox.Tooltip = {'If enabled the created .csv file will be automaticaly deleted after creating the data inspector file.'};
            app.DeletecsvfileCheckBox.Text = 'Delete .csv file';
            app.DeletecsvfileCheckBox.Value = true;
            app.ExportasdistanceCheckBox = uicheckbox(app.DataInspectorPanel);
            app.ExportasdistanceCheckBox.Tooltip = {'Exports the data against the distance instead of the time.'};
            app.ExportasdistanceCheckBox.Text = 'Export as distance';
            app.ExportasdistanceCheckBox.Value = true;
            app.Results2RunPanel = uipanel(app.SimulationResultsTab);
            app.Results2RunPanel.Title = ' Results 2. Run';
            app.t_lapLabel_2 = uilabel(app.Results2RunPanel);
            app.t_lapLabel_2.Text = 'Lap time =';
            app.LapDistanceLabel_2 = uilabel(app.Results2RunPanel);
            app.LapDistanceLabel_2.Text = 'Lap Distance =';
            app.PowerLimitLabel_2 = uilabel(app.Results2RunPanel);
            app.PowerLimitLabel_2.Text = 'Power Limit =';
            app.EnduranceDistanceLabel_2 = uilabel(app.Results2RunPanel);
            app.EnduranceDistanceLabel_2.Text = 'Endurance Distance =';
            app.EndurancetimeLabel_2 = uilabel(app.Results2RunPanel);
            app.EndurancetimeLabel_2.Text = 'Endurance time =';
            app.ComputationtimeLabel_2 = uilabel(app.Results2RunPanel);
            app.ComputationtimeLabel_2.Text = 'Computation time:';
            app.withrecuperationLabel_2 = uilabel(app.Results2RunPanel);
            app.withrecuperationLabel_2.Text = 'with recuperation =';
            app.EnergyconsumptionLabel_2 = uilabel(app.Results2RunPanel);
            app.EnergyconsumptionLabel_2.Text = 'Energy consumption = ';
            app.SkidPadTimeLabel_2 = uilabel(app.Results2RunPanel);
            app.SkidPadTimeLabel_2.Text = 'SkidPad Time =';
            app.SkidPadSpeedLabel_2 = uilabel(app.Results2RunPanel);
            app.SkidPadSpeedLabel_2.Text = 'SkidPad Speed =';
            app.DeltaSecondRunFirstRunPanel = uipanel(app.SimulationResultsTab);
            app.DeltaSecondRunFirstRunPanel.Title = 'Delta (Second Run - First Run)';
            app.t_lapLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.t_lapLabel_3.Text = 'Lap time =';
            app.EndurancetimeLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.EndurancetimeLabel_3.Text = 'Endurance time =';
            app.LapDistanceLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.LapDistanceLabel_3.Text = 'Lap Distance =';
            app.EnergyconsumptionWOrecuperationLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.EnergyconsumptionWOrecuperationLabel_3.Text = 'Energy consumption W/O recuperation =';
            app.WrecuperationLabel_3 = uilabel(app.DeltaSecondRunFirstRunPanel);
            app.WrecuperationLabel_3.Text = ' W recuperation =';
            app.SensitivityAnalysisPanel_2 = uipanel(app.SimulationResultsTab);
            app.SensitivityAnalysisPanel_2.Title = 'Sensitivity Analysis';
            app.xaxisDropDown_2Label = uilabel(app.SensitivityAnalysisPanel_2);
            app.xaxisDropDown_2Label.Text = 'x-axis';
            app.xaxisDropDown_2 = uidropdown(app.SensitivityAnalysisPanel_2);
            app.xaxisDropDown_2.ItemsData = {'1', '2', '3'};
            app.xaxisDropDown_2.Value = '1';
            app.yaxisDropDown_2Label = uilabel(app.SensitivityAnalysisPanel_2);
            app.yaxisDropDown_2Label.Text = 'y-axis';
            app.yaxisDropDown_2 = uidropdown(app.SensitivityAnalysisPanel_2);
            app.yaxisDropDown_2.ItemsData = {'1', '2', '3'};
            app.yaxisDropDown_2.Value = '1';
            app.DrawSensitivityPlotButton = uibutton(app.SensitivityAnalysisPanel_2, 'push');
            app.DrawSensitivityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSensitivityPlotButtonPushed, true);
            app.DrawSensitivityPlotButton.Text = 'Draw Sensitivity Plot';
            app.DrawDotsCheckBox = uicheckbox(app.SensitivityAnalysisPanel_2);
            app.DrawDotsCheckBox.Tooltip = {'Enables Dots for Data Points.'};
            app.DrawDotsCheckBox.Text = 'Draw Dots';
            app.Panel = uipanel(app.SensitivityAnalysisPanel_2);
            app.zaxisDropDown_2Label = uilabel(app.Panel);
            app.zaxisDropDown_2Label.Text = 'z-axis';
            app.zaxisDropDown_2 = uidropdown(app.Panel);
            app.zaxisDropDown_2.ItemsData = {'1', '2', '3'};
            app.zaxisDropDown_2.Enable = 'off';
            app.zaxisDropDown_2.Tooltip = {'z-axis should be set to laptime (t_tot) for most analyses.'};
            app.zaxisDropDown_2.Value = '1';
            app.zAxisCheckBox = uicheckbox(app.Panel);
            app.zAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @zAxisCheckBoxValueChanged, true);
            app.zAxisCheckBox.Tooltip = {'Enable third axis for 3D Plots. Should be set to t_tot most of the times.'};
            app.zAxisCheckBox.Text = '';
            app.ResolutionxEditFieldLabel = uilabel(app.Panel);
            app.ResolutionxEditFieldLabel.Text = 'Resolution x';
            app.ResolutionxEditField = uieditfield(app.Panel, 'numeric');
            app.ResolutionxEditField.LowerLimitInclusive = 'off';
            app.ResolutionxEditField.UpperLimitInclusive = 'off';
            app.ResolutionxEditField.Limits = [0 Inf];
            app.ResolutionxEditField.Enable = 'off';
            app.ResolutionxEditField.Tooltip = {'Set resolution to high values for big number (for example P_max) and to low numbers for small numbers (for example c_w).'};
            app.ResolutionxEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.Panel);
            app.yEditFieldLabel.Text = 'y';
            app.ResolutionyEditField = uieditfield(app.Panel, 'numeric');
            app.ResolutionyEditField.LowerLimitInclusive = 'off';
            app.ResolutionyEditField.UpperLimitInclusive = 'off';
            app.ResolutionyEditField.Limits = [0 Inf];
            app.ResolutionyEditField.Enable = 'off';
            app.ResolutionyEditField.Tooltip = {'Set resolution to high values for big number (for example P_max) and to low numbers for small numbers (for example c_w).'};
            app.ResolutionyEditField.Value = 1;
            app.PlotResultsPanel = uipanel(app.SimulationResultsTab);
            app.PlotResultsPanel.Title = 'Plot Results';
            app.SelectPlotDropDownLabel = uilabel(app.PlotResultsPanel);
            app.SelectPlotDropDownLabel.Text = 'Select Plot';
            app.SelectPlotDropDown = uidropdown(app.PlotResultsPanel);
            app.SelectPlotDropDown.Items = {'Cell Current', 'Slip', 'Power Map (P_M)', 'RPM', 'Brake', 'Tire Load Front', 'Tire Load Rear', 'Axle Load', 'Dynamic Tire Radius', 'Lateral Forces Front', 'Lateral Forces Rear', 'Conti Plot', 'Tractive Power Rear', 'Traction limit', 'Draw Track', 'Used Energy', 'g-g-V Plot', 'Velocity m/s', 'Velocity km/h', 'Aero Tire Load', 'Motor Efficency', 'Accelerations m/s', 'Power Map (P_el)', 'P_el', 'Lateral Acceleration Track', 'Longitudinal Acceleration Track', 'Acceleration g', 'Accelerations Plot', 'Virtual Current Cellpack', 'Accumulator Voltage', 'P_tractive', 'Velocity Map', 'DRS Status Map', 'Track Analyzer', 'RPM Map', 'Torque Map', 'Torque Plot', 'Gearselection Map'};
            app.SelectPlotDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38'};
            app.SelectPlotDropDown.Value = '1';
            app.DrawPlot1RunButton = uibutton(app.PlotResultsPanel, 'push');
            app.DrawPlot1RunButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPlot1RunButtonPushed, true);
            app.DrawPlot1RunButton.Text = 'Draw Plot 1. Run';
            app.DrawPlot2RunButton = uibutton(app.PlotResultsPanel, 'push');
            app.DrawPlot2RunButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPlot2RunButtonPushed, true);
            app.DrawPlot2RunButton.Text = 'Draw Plot 2. Run';
            app.RunnumberEditFieldLabel = uilabel(app.PlotResultsPanel);
            app.RunnumberEditFieldLabel.Text = 'Run number';
            app.RunnumberEditField = uieditfield(app.PlotResultsPanel, 'numeric');
            app.RunnumberEditField.Limits = [1 Inf];
            app.RunnumberEditField.RoundFractionalValues = 'on';
            app.RunnumberEditField.Value = 1;
            app.CompareRunsPanel = uipanel(app.SimulationResultsTab);
            app.CompareRunsPanel.Title = 'Compare Runs';
            app.CompareRunPlotsButton = uibutton(app.CompareRunsPanel, 'push');
            app.CompareRunPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareRunPlotsButtonPushed, true);
            app.CompareRunPlotsButton.Text = 'Compare Run Plots';
            app.SelectPlotDropDown_3Label = uilabel(app.CompareRunsPanel);
            app.SelectPlotDropDown_3Label.Text = 'Select Plot';
            app.SelectPlotDropDown_2 = uidropdown(app.CompareRunsPanel);
            app.SelectPlotDropDown_2.Items = {'RPM', 'Downforce', 'Torque', 'Power', 'Drag', 'Driving Resistance', 'Velocity m/s', 'Velocity km/h', 'Cellcapacity', 'Motor efficiency', 'Accelerations g'};
            app.SelectPlotDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SelectPlotDropDown_2.Value = '1';
            app.Copyright2021BalticRacingbyEricDorniedenLabel_4 = uilabel(app.SimulationResultsTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel_4.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.OpenTrackAnalyzerButton = uibutton(app.SimulationResultsTab, 'push');
            app.OpenTrackAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTrackAnalyzerButtonPushed, true);
            app.OpenTrackAnalyzerButton.Text = 'Open Track Analyzer';
            app.TelemetryconverterButton = uibutton(app.SimulationResultsTab, 'push');
            app.TelemetryconverterButton.ButtonPushedFcn = createCallbackFcn(app, @TelemetryconverterButtonPushed, true);
            app.TelemetryconverterButton.Text = 'Telemetry converter';
            app.SimulationReplayWIPTab = uitab(app.TabGroup);
            app.SimulationReplayWIPTab.Title = 'Simulation Replay [WIP]';
            app.UIAxes3 = uiaxes(app.SimulationReplayWIPTab);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTickLabel = '';
            app.UIAxes4 = uiaxes(app.SimulationReplayWIPTab);
            zlabel(app.UIAxes4, 'Z')
            app.LoadReplayButton = uibutton(app.SimulationReplayWIPTab, 'push');
            app.LoadReplayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReplayButtonPushed, true);
            app.LoadReplayButton.Text = 'Load Run';
            app.RunNumberSpinnerLabel = uilabel(app.SimulationReplayWIPTab);
            app.RunNumberSpinnerLabel.Text = 'Run Number';
            app.RunNumberSpinner = uispinner(app.SimulationReplayWIPTab);
            app.RunNumberSpinner.Limits = [1 Inf];
            app.RunNumberSpinner.Value = 1;
            app.Copyright2021BalticRacingbyEricDorniedenLabel_5 = uilabel(app.SimulationReplayWIPTab);
            app.Copyright2021BalticRacingbyEricDorniedenLabel_5.Text = 'Copyright © 2021 Baltic Racing by Eric Dornieden';
            app.Image2_3 = uiimage(app.SimulationReplayWIPTab);
            app.Image2_3.ImageSource = 'balticracing_logo_transparent.png';
            app.Image_3 = uiimage(app.SimulationReplayWIPTab);
            app.Image_3.ImageSource = 'Logo_Ship_HKS_Ranged.png';
            app.Panel_2 = uipanel(app.SimulationReplayWIPTab);
            app.Panel_2.Title = 'Panel';
            app.DropDown = uidropdown(app.Panel_2);
            app.Label = uilabel(app.Panel_2);
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.Label_2 = uilabel(app.Panel_2);
            app.DropDown_3 = uidropdown(app.Panel_2);
            app.Label_3 = uilabel(app.Panel_2);
            app.DropDown_4 = uidropdown(app.Panel_2);
            app.Label_4 = uilabel(app.Panel_2);
            app.DropDown_5 = uidropdown(app.Panel_2);
            app.Label_5 = uilabel(app.Panel_2);
            app.DropDown_6 = uidropdown(app.Panel_2);
            app.Label_6 = uilabel(app.Panel_2);
            app.Gauge = uigauge(app.Panel_2, 'linear');
            app.Gauge_2 = uigauge(app.Panel_2, 'linear');
            app.Gauge_3 = uigauge(app.Panel_2, 'linear');
            app.GearLabel = uilabel(app.Panel_2);
            app.GearLabel.Text = 'Gear: ';
            app.RPMLabel = uilabel(app.Panel_2);
            app.RPMLabel.Text = 'RPM: ';
            app.Gauge_4 = uigauge(app.Panel_2, 'linear');
            app.SpeedLabel = uilabel(app.Panel_2);
            app.SpeedLabel.Text = 'Speed:';
            app.RPMGauge = uigauge(app.Panel_2, 'circular');
            app.RPMGauge.Limits = [0 6000];
            app.DistanceLabel = uilabel(app.Panel_2);
            app.DistanceLabel.Text = 'Distance:';
            app.Image4 = uiimage(app.Panel_2);
            app.Image4.ImageSource = 'SteeringWheel1.png';
            app.RadiusLabel = uilabel(app.Panel_2);
            app.RadiusLabel.Text = 'Radius:';
            app.TimeLabel = uilabel(app.Panel_2);
            app.TimeLabel.Text = 'Time:';
            app.PlayButton = uibutton(app.SimulationReplayWIPTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.SimulationReplayWIPTab, 'push');
            app.StopButton.Text = 'Stop';
            app.Slider = uislider(app.SimulationReplayWIPTab);
            app.DRSStatusLampLabel = uilabel(app.SimulationReplayWIPTab);
            app.DRSStatusLampLabel.Text = 'DRS Status';
            app.DRSStatusLamp = uilamp(app.SimulationReplayWIPTab);
            app.BrakeLampLabel = uilabel(app.SimulationReplayWIPTab);
            app.BrakeLampLabel.Text = 'Brake';
            app.BrakeLamp = uilamp(app.SimulationReplayWIPTab);
            app.Slider_2 = uislider(app.SimulationReplayWIPTab);
            app.Slider_2.Limits = [0.5 3];
            app.Slider_2.Value = 1;
            % Show the figure after all components are created
            app.LaptimeSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laptime_Simulation_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaptimeSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaptimeSimulationUIFigure)
        end
    end
end